classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        ValuePanel  matlab.ui.container.Panel
        LampRejim3  matlab.ui.control.Lamp
        Label_5     matlab.ui.control.Label
        LampRejim2  matlab.ui.control.Lamp
        Label_4     matlab.ui.control.Label
        LampRejim1  matlab.ui.control.Lamp
        Label_3     matlab.ui.control.Label
        Regim       matlab.ui.container.ButtonGroup
        ButtRegim3  matlab.ui.control.RadioButton
        ButtRegim2  matlab.ui.control.RadioButton
        ButtRegim1  matlab.ui.control.RadioButton
        Tab_2       matlab.ui.container.Tab
        PlotPanel   matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        Tab_3       matlab.ui.container.Tab
        UITable2    matlab.ui.control.Table
        Knob        matlab.ui.control.Knob
        Go          matlab.ui.control.Lamp
        Label_2     matlab.ui.control.Label
        Switch      matlab.ui.control.ToggleSwitch
        Gauge       matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        flag = 0; % Description
        m; % Description
        registranalog;
        regim=2;
        zapusk=1;
    end
    
    methods (Access = private)
        
        function func(app)
              app.m = modbus('tcpip', '192.168.1.123', 80);
              while(app.flag)
                if(app.zapusk==1)
                    app.zapusk=0;
                    write(app.m,'coils',1,(1));
                    write(app.m,'coils',app.regim,(1))
                end
                zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                zapros_disk=read(app.m,'coils',1,50);
                registrdisk=transpose(zapros_disk);
                app.registranalog=transpose(zapros_analog);
                app.UITable2.Data=([app.registranalog,registrdisk]);
                app.Gauge.Value=zapros_analog(1);
                pause(1)
              end
              write(app.m,'coils',1,(1))
              app.zapusk=1;
              clear m;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
         value = app.Switch.Value;
            if strcmp(value,'Вкл')
                app.Go.Color='g';
                app.flag = 1;
                func(app)
            else
                app.flag = 0;
                app.Go.Color='r';
            end
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value=app.Knob.Value;  
            if(app.flag==1)
                write(app.m,'holdingregs',1,floor(value),'uint16')
            end
        end

        % Selection changed function: Regim
        function RegimSelectionChanged(app, event)
            selectedButton = app.Regim.SelectedObject;
            if selectedButton==app.ButtRegim1
                if(app.flag==0)
                 app.LampRejim1.Color='g';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='r';
                 app.regim=2;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='g';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='r';
                 write(app.m,'coils',2,(1));
                 write(app.m,'coils',3,(0));
                 write(app.m,'coils',4,(0));
                end
            end
            if selectedButton==app.ButtRegim2
                if(app.flag==0)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='g';
                 app.LampRejim3.Color='r';
                 app.regim=3;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='g';
                 app.LampRejim3.Color='r';
                 write(app.m,'coils',2,(0));
                 write(app.m,'coils',3,(1));
                 write(app.m,'coils',4,(0));
                end
            end
            if selectedButton==app.ButtRegim3
                if(app.flag==0)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='g';
                 app.regim=4;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='g';
                 write(app.m,'coils',2,(0));
                 write(app.m,'coils',3,(0));
                 write(app.m,'coils',4,(1));
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.Gauge = uigauge(app.ValuePanel, 'circular');
            app.Gauge.Limits = [0 1000];
            app.Switch = uiswitch(app.ValuePanel, 'toggle');
            app.Switch.Items = {'Выкл', 'Вкл'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Выкл';
            app.Label_2 = uilabel(app.ValuePanel);
            app.Label_2.Text = {'Запуск'; ''};
            app.Go = uilamp(app.ValuePanel);
            app.Knob = uiknob(app.ValuePanel, 'continuous');
            app.Knob.Limits = [0 1000];
            app.Knob.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Knob.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Regim = uibuttongroup(app.ValuePanel);
            app.Regim.SelectionChangedFcn = createCallbackFcn(app, @RegimSelectionChanged, true);
            app.Regim.Title = 'Выбор режима';
            app.ButtRegim1 = uiradiobutton(app.Regim);
            app.ButtRegim1.Text = 'Режим 1';
            app.ButtRegim1.Value = true;
            app.ButtRegim2 = uiradiobutton(app.Regim);
            app.ButtRegim2.Text = 'Режим 2';
            app.ButtRegim3 = uiradiobutton(app.Regim);
            app.ButtRegim3.Text = 'Режим 3';
            app.Label_3 = uilabel(app.ValuePanel);
            app.Label_3.Text = 'Режим 1';
            app.LampRejim1 = uilamp(app.ValuePanel);
            app.Label_4 = uilabel(app.ValuePanel);
            app.Label_4.Text = 'Режим 2';
            app.LampRejim2 = uilamp(app.ValuePanel);
            app.Label_5 = uilabel(app.ValuePanel);
            app.Label_5.Text = 'Режим 3';
            app.LampRejim3 = uilamp(app.ValuePanel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab_2);
            app.PlotPanel.Title = 'График';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab_3);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end